classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure                % UI Figure
        Label        matlab.ui.control.Label         % Welcome to InvesTone
        Button2      matlab.ui.control.Button        % Stop
        Button4      matlab.ui.control.Button        % Play
        ButtonGroup  matlab.ui.container.ButtonGroup % $TSLA (Tesla)
        Button11     matlab.ui.control.Button        % Normal
        Button12     matlab.ui.control.Button        % Stock
        ButtonGroup2 matlab.ui.container.ButtonGroup % $LNKD (LinkedIn)
        Button9      matlab.ui.control.Button        % Normal
        Button10     matlab.ui.control.Button        % Stock
        ButtonGroup3 matlab.ui.container.ButtonGroup % $COST (Costco)
        Button7      matlab.ui.control.Button        % Normal
        Button8      matlab.ui.control.Button        % Stock
        ButtonGroup4 matlab.ui.container.ButtonGroup % $TWTR (Twitter)
        Button5      matlab.ui.control.Button        % Normal
        Button6      matlab.ui.control.Button        % Stock
    end


    properties (Access = public)
        y;
        Fs;
        player; 
        paused;
        start;
        songname;
        company;
    end

    methods (Access = private)
        
        function results = stopaudio(app)
            results = 0;
            if strcmp(app.player.Running, 'on')
                stop(app.player);
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start = 1;
        end

        % Button button pushed function
        function readaudio(app)
            app.songname = 'stairway';             
        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            if app.start ~= 0
                if strcmp(app.player.Running, 'on')
                    stop(app.player);
                    close(gcf);
                end
            end
            
        end

        % Button4 button pushed function
        function Button4ButtonPushed(app)
            if app.start ~= 0
                if strcmp(app.player.Running, 'off')
                    app.player.TimerFcn = {@stopaudio, app.player}; 
                    app.player.TimerPeriod = 30;
                    if strcmp(app.company, 'twtr')
                        openfig('twtr');
                    elseif strcmp(app.company, 'lnkd')
                        openfig('lnkd');
                    elseif strcmp(app.company, 'cost')
                        openfig('cost');
                    elseif strcmp(app.company, 'tsla')
                        openfig('tsla');
                    end
                    play(app.player);
                end
            end
        end

        % Button3 button pushed function
        function Button3ButtonPushed(app)

        end

        % Button5 button pushed function
        function twtrnormal(app)
            if app.start ~= 0
                [app.y,app.Fs] = audioread('cutfurelise.mp3');
                app.player = audioplayer(app.y, app.Fs);
                app.paused = 0;
                app.company = 'twtr';
            end
        end

        % Button7 button pushed function
        function costnormal(app)
            if app.start ~= 0
                [app.y,app.Fs] = audioread('cutfurelise.mp3');
                app.player = audioplayer(app.y, app.Fs);
                app.paused = 0; 
                app.company = 'cost';
            end
        end

        % Button9 button pushed function
        function lnkdnormal(app)
            if app.start ~= 0
                [app.y,app.Fs] = audioread('cutfurelise.mp3');
                app.player = audioplayer(app.y, app.Fs);
                app.paused = 0;
                app.company = 'lnkd';
            end
        end

        % Button11 button pushed function
        function tslanormal(app)
            if app.start ~= 0
                [app.y,app.Fs] = audioread('cutfurelise.mp3');
                app.player = audioplayer(app.y, app.Fs);
                app.paused = 0;
                app.company = 'tsla';
            end
        end

        % Button13 button pushed function
        function readaudio2(app)
            app.songname = 'cliffs';
        end

        % Button6 button pushed function
        function twtrstock(app)
            if app.start ~= 0
                [app.y,app.Fs] = audioread('cutfurelisetwtr.m4a');
                app.player = audioplayer(app.y, app.Fs);
                app.paused = 0;
                app.company = 'twtr';
            end
        end

        % Button8 button pushed function
        function coststock(app)
            if app.start ~= 0
                [app.y,app.Fs] = audioread('cutfurelisecostco.m4a');
                app.player = audioplayer(app.y, app.Fs);
                app.paused = 0;
                app.company = 'cost';
            end
        end

        % Button10 button pushed function
        function lnkdstock(app)
            if app.start ~= 0
                [app.y,app.Fs] = audioread('cutfureliselnkd.m4a');
                app.player = audioplayer(app.y, app.Fs);
                app.paused = 0;
                app.company = 'lnkd';
            end
        end

        % Button12 button pushed function
        function tslastock(app)
            if app.start ~= 0
                [app.y,app.Fs] = audioread('cutfurelisetsla.m4a');
                app.player = audioplayer(app.y, app.Fs);
                app.paused = 0;
                app.company = 'tsla';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Welcome to InvesTone';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = 'Stop';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4ButtonPushed);
            app.Button4.Text = 'Play';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '$TSLA (Tesla)';
            app.ButtonGroup.Units = 'pixels';
            app.Button11 = uibutton(app.ButtonGroup, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @tslanormal);
            app.Button11.Text = 'Normal';
            app.Button12 = uibutton(app.ButtonGroup, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @tslastock);
            app.Button12.Text = 'Stock';
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.Title = '$LNKD (LinkedIn)';
            app.ButtonGroup2.Units = 'pixels';
            app.Button9 = uibutton(app.ButtonGroup2, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @lnkdnormal);
            app.Button9.Text = 'Normal';
            app.Button10 = uibutton(app.ButtonGroup2, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @lnkdstock);
            app.Button10.Text = 'Stock';
            app.ButtonGroup3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup3.Title = '$COST (Costco)';
            app.ButtonGroup3.Units = 'pixels';
            app.Button7 = uibutton(app.ButtonGroup3, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @costnormal);
            app.Button7.Text = 'Normal';
            app.Button8 = uibutton(app.ButtonGroup3, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @coststock);
            app.Button8.Text = 'Stock';
            app.ButtonGroup4 = uibuttongroup(app.UIFigure);
            app.ButtonGroup4.Title = '$TWTR (Twitter)';
            app.ButtonGroup4.Units = 'pixels';
            app.Button5 = uibutton(app.ButtonGroup4, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @twtrnormal);
            app.Button5.Text = 'Normal';
            app.Button6 = uibutton(app.ButtonGroup4, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @twtrstock);
            app.Button6.Text = 'Stock';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end